classdef chirpGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChirpUIFigure                 matlab.ui.Figure
        File                          matlab.ui.container.Menu
        Load                          matlab.ui.container.Menu
        Save                          matlab.ui.container.Menu
        SampleRateEditField           matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel      matlab.ui.control.Label
        GenerateGraphButton           matlab.ui.control.Button
        ReferenceTimeEditField        matlab.ui.control.NumericEditField
        ReferenceTimeEditFieldLabel   matlab.ui.control.Label
        StartFrequencyEditField       matlab.ui.control.NumericEditField
        StartFrequencyEditFieldLabel  matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        ReferenceFrequencyEditField   matlab.ui.control.NumericEditField
        ReferenceFrequencyEditFieldLabel  matlab.ui.control.Label
        ChirpLabel                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            f0 = app.StartFrequencyEditField.Value;
            f1 = app.ReferenceFrequencyEditField.Value;
            d = app.DurationEditField.Value;
            fs = app.SampleRateEditField.Value;
            dt = 1/fs;
            t = 0:dt:d;
            t1 = app.ReferenceTimeEditField.Value;
            testSignal = chirp(t,f0,t1,f1);  
            setappdata(0,'StartFrequency',f0);
            setappdata(0,'SampleRate',fs);
            setappdata(0,'ReferenceFrequency',f1);
            setappdata(0,'ReferenceTime',t1);
            setappdata(0,'Duration',t);
            setappdata(0,'TestSignal',testSignal);
            setappdata(0,'Seconds',d);
            
            graphs
            close(app.ChirpUIFigure);
        end

        % Menu selected function: Load
        function LoadMenuSelected(app, event)
             % loads the values from the mat file
            file = uigetfile();
            vars = load(file);
            app.DurationEditField.Value = vars.duration;
            app.StartFrequencyEditField.Value = vars.frequency;
            app.ReferenceFrequencyEditField.Value = vars.refFrequency;
            app.ReferenceTimeEditField.Value = vars.refTime;
            app.SampleRateEditField.Value = vars.sampleRate;
        end

        % Menu selected function: Save
        function SaveMenuSelected(app, event)
            % opens a save menu dialog box and saves the values of the
            % variables in a mat file
            [filename,pathname] = uiputfile('*','Save Chirp Values','chirp.mat');
            sinusoidValues = fullfile(pathname, filename);
            duration = app.DurationEditField.Value;
            startFreq = app.StartFrequencyEditField.Value;
            refFreq = app.ReferenceFrequencyEditField.Value;
            refTime = app.ReferenceTimeEditField.Value;
            sampleRate = app.SampleRateEditField.Value;
            save(sinusoidValues,"duration","startFreq","sampleRate","refFreq","refTime");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChirpUIFigure = uifigure('Visible', 'off');
            app.ChirpUIFigure.Name = 'Chirp';
            app.ChirpUIFigure.Tag = 'chirp';
            app.File = uimenu(app.ChirpUIFigure);
            app.File.Text = 'File';
            app.Load = uimenu(app.File);
            app.Load.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.Load.Accelerator = 'L';
            app.Load.Text = 'Load';
            app.Save = uimenu(app.File);
            app.Save.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.Save.Accelerator = 'S';
            app.Save.Text = 'Save';
            app.ChirpLabel = uilabel(app.ChirpUIFigure);
            app.ChirpLabel.Text = 'Chirp ';
            app.ReferenceFrequencyEditFieldLabel = uilabel(app.ChirpUIFigure);
            app.ReferenceFrequencyEditFieldLabel.Text = 'Reference Frequency';
            app.ReferenceFrequencyEditField = uieditfield(app.ChirpUIFigure, 'numeric');
            app.ReferenceFrequencyEditField.Value = 500;
            app.DurationEditFieldLabel = uilabel(app.ChirpUIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.ChirpUIFigure, 'numeric');
            app.DurationEditField.Value = 5;
            app.StartFrequencyEditFieldLabel = uilabel(app.ChirpUIFigure);
            app.StartFrequencyEditFieldLabel.Text = 'Start Frequency';
            app.StartFrequencyEditField = uieditfield(app.ChirpUIFigure, 'numeric');
            app.ReferenceTimeEditFieldLabel = uilabel(app.ChirpUIFigure);
            app.ReferenceTimeEditFieldLabel.Text = 'Reference Time';
            app.ReferenceTimeEditField = uieditfield(app.ChirpUIFigure, 'numeric');
            app.ReferenceTimeEditField.Value = 1;
            app.GenerateGraphButton = uibutton(app.ChirpUIFigure, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Tag = 'chirp';
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.SampleRateEditFieldLabel = uilabel(app.ChirpUIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.ChirpUIFigure, 'numeric');
            app.SampleRateEditField.Value = 1000;
            % Show the figure after all components are created
            app.ChirpUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chirpGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChirpUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChirpUIFigure)
        end
    end
end