classdef graphs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        graphsUIFigure  matlab.ui.Figure
        FileMenu        matlab.ui.container.Menu
        ImportMenu      matlab.ui.container.Menu
        ExportMenu      matlab.ui.container.Menu
        Spectrogram     matlab.ui.control.UIAxes
        UIAxes4         matlab.ui.control.UIAxes
        NoisySignal     matlab.ui.control.UIAxes
        TestSignal      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function removeAppData(app)
             % remove app data
            appdata = get(0,'ApplicationData');
            fns = fieldnames(appdata);
            for ii = 1:numel(fns)
              rmappdata(0,fns{ii});
            end
        end
        
        function [instnfrq,axes] = graphNoisySignal(app,fs,t,testSignal)
            % plots the noisy signal
            r = 0.001*randn(1,length(t));
            noisySignal = testSignal + r;
            nh = hilbert(noisySignal);
            instnfrq = fs/(2*pi)*diff(unwrap(angle(nh)));
            axes = app.NoisySignal;
            plot(app.NoisySignal,t(1:length(t)-1),instnfrq);
        end
        
        function functionName = parseFileFormatExport(app,fileFormat)
            signalType = getappdata(0,'signalType');
            fileFcnType = fileSelector(app,fileFormat);
            ExportFunctionSuffix = strcat(fileFcnType,'Save');
            functionName = strcat(signalType,ExportFunctionSuffix);
        end


        function fileFcnType = fileSelector(~,fileFormat)
            if strcmp(fileFormat,'.xlsx') == 1 || strcmp(fileFormat, '.xls') == 1
                fileFcnType = 'Excel';
            elseif strcmp(fileFormat, '.mat') == 1
                fileFcnType = '';
            elseif strcmp(fileFormat,'.bin') == 1
                fileFcnType = 'Binary';
            elseif strcmp(fileFormat, '.txt') == 1
                fileFcnType = 'Text';
            end           
        end

        function functionName = parseFileFormatImport(app,fileFormat)
            fileFcnType = fileSelector(app,fileFormat);
            functionName = strcat(fileFcnType, 'Load');
        end        
        
        function ax = createNewFigure(~,children)
            f = figure();
            % copyobj(ax1,f);
            ax=axes('Parent',f); 
            copyobj(children,ax);
        end
        
        function runGraphs(app,type,process,signalRep,signalProcess,fs,t,testSignal,filterType)
            representationFcn = strcat(signalRep,type);
            feval(representationFcn,app,t,testSignal);
            processFcn = strcat(signalProcess,process);
            feval(processFcn,app,testSignal);            
            freq = graphNoisySignal(app,fs,t,testSignal);
            filterNoSpace = filterType(find(~isspace(filterType)));
            feval(filterNoSpace,axes,t,freq,fs);
        end
    end

    methods (Access = public)
     
        function LoadFileVariablesIntoUI(app)
            type = 'Type';
            process = 'Process';
            t = getappdata(0,'Duration');
            testSignal = getappdata(0,'TestSignal');
            fs = getappdata(0,'SampleRate');
            filterType = getappdata(0,'FilterType');
            signalRep = getappdata(0,'signalRepresentation');
            signalProcess = getappdata(0,'signalProcess');
            runGraphs(app,type,process,signalRep,signalProcess,fs,t,testSignal,filterType);
        end
            
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fs  = getappdata(0,'SampleRate');
            % run code based on the signal type
            testSignal = getappdata(0,'TestSignal');
            signalRep = getappdata(0,'signalRepresentation');
            signalProcess = getappdata(0,'signalProcess');
            t = getappdata(0,'Duration');
            filterType = getappdata(0,'FilterType');
            % reads the signal representation type which determines
            % the real or iq data type
            type = 'Type';
            process = 'Process';    
            runGraphs(app,type,process,signalRep,signalProcess,fs,t,testSignal,filterType);

        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            filter = {'*.mat';'*.xlsx';'*.bin';'*.txt';'*.*'};
            [filename,pathname] = uiputfile(filter,'Save','graphs.mat');
            [pathstr,name,ext] = fileparts(filename);
            fileFormat = ext;
            fileSaveFcn = parseFileFormatExport(app,fileFormat);
            feval(fileSaveFcn,filename);
                                 
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            filter = {'*.mat';'*.xlsx';'*.bin';'*.txt';'*.*'};
            filename = uigetfile(filter,'Open');
            [pathstr,name,ext] = fileparts(filename);
            removeAppData(app);
            fileFormat = ext;
            LoadTypeFcn = parseFileFormatImport(app,fileFormat);
            feval(LoadTypeFcn,filename);
            LoadFileVariablesIntoUI(app);
    
        end

        % Close request function: graphsUIFigure
        function graphsUIFigureCloseRequest(app, event)
            removeAppData(app)
            delete(app)
        end

        % Button down function: TestSignal
        function TestSignalButtonDown(app, event)
            testChildren = app.TestSignal.Children;
            createNewFigure(app,testChildren);
            set(gca,'XLim',[0 0.03]);
        end

        % Button down function: NoisySignal
        function NoisySignalButtonDown(app, event)

            noisyChildren = app.NoisySignal.Children;
            createNewFigure(app,noisyChildren);   
        end

        % Button down function: Spectrogram
        function SpectrogramButtonDown(app, event)
            spectrogramChildren = app.Spectrogram.Children;
            createNewFigure(app,spectrogramChildren); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.graphsUIFigure = uifigure('Visible', 'off');
            app.graphsUIFigure.Name = 'graphs';
            app.graphsUIFigure.CloseRequestFcn = createCallbackFcn(app, @graphsUIFigureCloseRequest, true);
            app.graphsUIFigure.Tag = 'graphs';
            app.FileMenu = uimenu(app.graphsUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.TestSignal = uiaxes(app.graphsUIFigure);
            title(app.TestSignal, 'Test Signal')
            xlabel(app.TestSignal, 'Time (s)')
            ylabel(app.TestSignal, 'Amplitude')
            zlabel(app.TestSignal, 'Z')
            app.TestSignal.ButtonDownFcn = createCallbackFcn(app, @TestSignalButtonDown, true);
            app.NoisySignal = uiaxes(app.graphsUIFigure);
            title(app.NoisySignal, 'Noisy Signal Frequencies')
            xlabel(app.NoisySignal, 'Time (s)')
            ylabel(app.NoisySignal, 'Frequency (Hz)')
            zlabel(app.NoisySignal, 'Z')
            app.NoisySignal.ButtonDownFcn = createCallbackFcn(app, @NoisySignalButtonDown, true);
            app.UIAxes4 = uiaxes(app.graphsUIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Spectrogram = uiaxes(app.graphsUIFigure);
            title(app.Spectrogram, 'Spectrogram')
            xlabel(app.Spectrogram, 'Time (s)')
            ylabel(app.Spectrogram, 'Frequency (Hz)')
            zlabel(app.Spectrogram, 'Z')
            app.Spectrogram.ButtonDownFcn = createCallbackFcn(app, @SpectrogramButtonDown, true);
            % Show the figure after all components are created
            app.graphsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.graphsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.graphsUIFigure)
        end
    end
end