classdef signalGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalGeneration              matlab.ui.Figure
        FilterTypeDropDown            matlab.ui.control.DropDown
        FilterTypeDropDownLabel       matlab.ui.control.Label
        SignalRepresentationLabel     matlab.ui.control.Label
        ProcessingDropDown            matlab.ui.control.DropDown
        ProcessingDropDownLabel       matlab.ui.control.Label
        SignalTypeDropDown            matlab.ui.control.DropDown
        SignalTypeDropDown_2Label     matlab.ui.control.Label
        SignalRepresentationDropDown  matlab.ui.control.DropDown
        SignalGeneratorSettingsLabel  matlab.ui.control.Label
        BeginButton                   matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            % when the begin button is pushed it opens the relevant input 
            % window
            signalType = app.SignalTypeDropDown.Value;
            signalRep = app.SignalRepresentationDropDown.Value;
            signalProcess = app.ProcessingDropDown.Value;
            filterType = app.FilterTypeDropDown.Value;
            setappdata(0,'signalType',signalType);
            setappdata(0,'FilterType',filterType);
            setappdata(0,'signalRepresentation',signalRep);
            setappdata(0,'signalProcess',signalProcess);
            fcnStr = 'Function';
            s = strcat(signalType,fcnStr);
            feval(s)
            close(app.SignalGeneration);
        end

        % Close request function: SignalGeneration
        function SignalGenerationCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function SaveMenuSelected(app, event)
            saveState(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalGeneration = uifigure('Visible', 'off');
            app.SignalGeneration.Name = 'SignalGenerator';
            app.SignalGeneration.CloseRequestFcn = createCallbackFcn(app, @SignalGenerationCloseRequest, true);
            app.SignalGeneration.Tag = 'signalGenerator';
            app.BeginButton = uibutton(app.SignalGeneration, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.SignalGeneratorSettingsLabel = uilabel(app.SignalGeneration);
            app.SignalGeneratorSettingsLabel.Text = 'Signal Generator Settings';
            app.SignalRepresentationDropDown = uidropdown(app.SignalGeneration);
            app.SignalRepresentationDropDown.Items = {'Real', 'IQ'};
            app.SignalRepresentationDropDown.Value = 'Real';
            app.SignalTypeDropDown_2Label = uilabel(app.SignalGeneration);
            app.SignalTypeDropDown_2Label.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.SignalGeneration);
            app.SignalTypeDropDown.Items = {'Sinusoidal', 'Chirp'};
            app.SignalTypeDropDown.Value = 'Sinusoidal';
            app.ProcessingDropDownLabel = uilabel(app.SignalGeneration);
            app.ProcessingDropDownLabel.Text = 'Processing';
            app.ProcessingDropDown = uidropdown(app.SignalGeneration);
            app.ProcessingDropDown.Items = {'Hilbert', 'Spectrogram'};
            app.ProcessingDropDown.Value = 'Hilbert';
            app.SignalRepresentationLabel = uilabel(app.SignalGeneration);
            app.SignalRepresentationLabel.Text = 'Signal Representation';
            app.FilterTypeDropDownLabel = uilabel(app.SignalGeneration);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.SignalGeneration);
            app.FilterTypeDropDown.Items = {'Low Pass', 'High Pass'};
            app.FilterTypeDropDown.Value = 'Low Pass';
            % Show the figure after all components are created
            app.SignalGeneration.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signalGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalGeneration)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalGeneration)
        end
    end
end