classdef sinusoidal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SinusoidUIFigure          matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        GenerateGraphButton       matlab.ui.control.Button
        DurationEditField         matlab.ui.control.NumericEditField
        DurationEditFieldLabel    matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        SampleRateEditField       matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel  matlab.ui.control.Label
        SinusoidalLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            f0 = app.FrequencyEditField.Value;
            fs = app.SampleRateEditField.Value;
            d = app.DurationEditField.Value;
            filterBool = app.AddFilterCheckBox.Value;
            dt = 1/fs;
            t = 0:dt:d;
            testSignal = sin(2*pi*f0*t);
            setappdata(0,'Frequency',f0);
            setappdata(0,'Filter',filterBool);
            setappdata(0,'TestSignal',testSignal);
            setappdata(0,'SampleRate',fs);
            setappdata(0,'Duration',t);
            setappdata(0,'Seconds',d);
            graphs
            close(app.SinusoidUIFigure);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            % loads the values from the mat file
            file = uigetfile();
            vars = load(file);
            app.DurationEditField.Value = vars.duration;
            app.FrequencyEditField.Value = vars.frequency;
            app.SampleRateEditField.Value = vars.sampleRate;

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % opens a save menu dialog box and saves the values of the
            % variables in a mat file
            filter = {'*.m';'*.mat';'*.*'};
            [filename,pathname] = uiputfile(filter,'Save Sinusoid Values','sinusoid.mat');
            sinusoidValues = fullfile(pathname, filename);
            duration = app.DurationEditField.Value;
            frequency = app.FrequencyEditField.Value;
            sampleRate = app.SampleRateEditField.Value;
            save(sinusoidValues,"duration","frequency","sampleRate");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SinusoidUIFigure = uifigure('Visible', 'off');
            app.SinusoidUIFigure.Name = 'Sinusoid';
            app.SinusoidUIFigure.Tag = 'sinusoidal';
            app.FileMenu = uimenu(app.SinusoidUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Accelerator = 'l';
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SinusoidalLabel = uilabel(app.SinusoidUIFigure);
            app.SinusoidalLabel.Text = 'Sinusoidal';
            app.SampleRateEditFieldLabel = uilabel(app.SinusoidUIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.SinusoidUIFigure, 'numeric');
            app.SampleRateEditField.Value = 8000;
            app.FrequencyEditFieldLabel = uilabel(app.SinusoidUIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SinusoidUIFigure, 'numeric');
            app.FrequencyEditField.Value = 60;
            app.DurationEditFieldLabel = uilabel(app.SinusoidUIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.SinusoidUIFigure, 'numeric');
            app.DurationEditField.Value = 5;
            app.GenerateGraphButton = uibutton(app.SinusoidUIFigure, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Tag = 'sinusoidal';
            app.GenerateGraphButton.Text = 'Generate Graph';
            % Show the figure after all components are created
            app.SinusoidUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sinusoidal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SinusoidUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SinusoidUIFigure)
        end
    end
end